classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SCALEButton        matlab.ui.control.Button
        TranslationButton  matlab.ui.control.Button
        RotateButton       matlab.ui.control.Button
        SaveDatasetButton  matlab.ui.control.Button
        LoadImageButton    matlab.ui.control.Button
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image % Stores the loaded image
        dataset % Stores the transformed images
        transformedImage 
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set default image if no image is loaded
            if isempty(app.image)
                app.image = imread('images\default.jpg'); % Replace 'default_image.jpg' with your default image path
                imshow(app.image, 'Parent', app.UIAxes);
                imshow(app.image, 'Parent', app.UIAxes_2);
            end

            % Set axes visibility and labels off
            set(app.UIAxes, 'Visible', 'off');
            set(app.UIAxes_2, 'Visible', 'off');
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            xlabel(app.UIAxes_2, '');
            ylabel(app.UIAxes_2, '');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.tif;*.jpg;*.png;*.bmp', 'Image Files (*.tif; *.jpg, *.png, *.bmp)'}, 'Select an image');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            app.image = imread(fullfile(pathname, filename));
            imshow(app.image, 'Parent', app.UIAxes);
            imshow(app.image, 'Parent', app.UIAxes_2);
  
            set(app.UIAxes, 'Visible', 'off');
            set(app.UIAxes_2, 'Visible', 'off');
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            % Create dialog box
            d = uifigure('Name', 'Rotate Image', 'Position', [500 500 250 150]);

            % Add rotation angle input field
            rotationLabel = uilabel(d, 'Text', 'Enter rotation angle:', 'Position', [20 90 150 20]);
            rotationInput = uieditfield(d, 'numeric', 'Position', [170 90 70 20]);

            % Add a button to apply rotation
            applyButton = uibutton(d, 'push', 'Text', 'Apply', 'Position', [90 30 70 30]);
            applyButton.ButtonPushedFcn = @(src, event) applyRotation(app, rotationInput.Value);

            function applyRotation(app, angle)
                % Apply rotation and display result
                transformedImage = imrotate(app.image, angle);
                imshow(transformedImage, 'Parent', app.UIAxes_2);
                app.dataset{end+1} = transformedImage;

                % Close the dialog box
                delete(d)
            end
        end

        % Button pushed function: SaveDatasetButton
        function SaveDatasetButtonPushed(app, event)
            
            % Check if the dataset is empty
            if isempty(app.dataset)
                disp('Dataset is empty. No images to save.')
                return;
            end

            % Prompt the user for the file name and location to save the images
            [filename, pathname] = uiputfile({'*.tif;*.jpg;*.png;*.bmp', 'Image Files (*.tif; *.jpg, *.png, *.bmp)'}, 'Save Dataset');

            % Check if the user canceled the operation
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end

            % Create a subfolder named "images" if it doesn't exist
            imageFolder = fullfile(pathname, 'images');
            if ~exist(imageFolder, 'dir')
                mkdir(imageFolder);
            end

            % Save each image in the dataset
          
                % Generate a unique file name for each image
                [~, name, ext] = fileparts(filename);
                uniqueFilename = sprintf('%s_%d%s', name, numel(app.dataset), ext);

                % Full file path to save the image
                fullFilePath = fullfile(imageFolder, uniqueFilename);

                % Save the image using imwrite
                imwrite(app.dataset{numel(app.dataset)}, fullFilePath);
         

            disp('Dataset saved successfully.')
        end

        % Button pushed function: TranslationButton
        function TranslationButtonPushed(app, event)

            % Create dialog box
            d = uifigure('Name', 'Translate Image', 'Position', [500 500 250 200]);

            % Add translation distance input fields
            xTranslationLabel = uilabel(d, 'Text', 'Enter shift along X-axis:', 'Position', [20 130 150 20]);
            xTranslationInput = uieditfield(d, 'numeric', 'Position', [170 130 70 20]);

            yTranslationLabel = uilabel(d, 'Text', 'Enter shift along Y-axis:', 'Position', [20 90 150 20]);
            yTranslationInput = uieditfield(d, 'numeric', 'Position', [170 90 70 20]);

            % Add a button to apply translation
            applyButton = uibutton(d, 'push', 'Text', 'Apply', 'Position', [90 30 70 30]);
            applyButton.ButtonPushedFcn = @(src, event) applyTranslation(app, xTranslationInput.Value, yTranslationInput.Value);

            function applyTranslation(app, xShift, yShift)
                % Apply translation and display result
                transformedImage = imtranslate(app.image, [xShift, yShift]);
                imshow(transformedImage, 'Parent', app.UIAxes_2);
                app.dataset{end+1} = transformedImage;

                % Close the dialog box
                delete(d)
            end

        end

        % Button pushed function: SCALEButton
        function SCALEButtonPushed(app, event)
            % Create dialog box
            d = uifigure('Name', 'Scale Image', 'Position', [500 500 250 150]);

            % Add scale factor input field
            scaleLabel = uilabel(d, 'Text', 'Enter scale factor:', 'Position', [20 90 150 20]);
            scaleInput = uieditfield(d, 'numeric', 'Position', [170 90 70 20]);

            % Add a button to apply scaling
            applyButton = uibutton(d, 'push', 'Text', 'Apply', 'Position', [90 30 70 30]);
            applyButton.ButtonPushedFcn = @(src, event) applyScaling(app, scaleInput.Value);

            function applyScaling(app, scaleFactor)
                % Apply scaling and display result
                transformedImage = imresize(app.image, scaleFactor);
                imshow(transformedImage, 'Parent', app.UIAxes_2);
                app.dataset{end+1} = transformedImage;

                % Close the dialog box
                delete(d)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Normal Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Transformed image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveDatasetButton = uibutton(app.UIFigure, 'push');
            app.SaveDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDatasetButtonPushed, true);
            app.SaveDatasetButton.Text = 'Save Dataset';
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.TranslationButton = uibutton(app.UIFigure, 'push');
            app.TranslationButton.ButtonPushedFcn = createCallbackFcn(app, @TranslationButtonPushed, true);
            app.TranslationButton.Text = 'Translation';
            app.SCALEButton = uibutton(app.UIFigure, 'push');
            app.SCALEButton.ButtonPushedFcn = createCallbackFcn(app, @SCALEButtonPushed, true);
            app.SCALEButton.Text = 'SCALE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end